classdef RevolutBudgetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        LoadMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        rawData % data as imported from file
        Type % Type column of data
        Product % Product column of data
        StartDate % Started Date column of data
        CompleteDate % Completed Date column of data
        Description % Description column of data
        Amount % Amount column of data
        Fee % Fee column of data
        Currency % Currency column of data
        State % State column of data
        Balance % Balance column of data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            %Load the data from the file
            [file,path] = uigetfile('account-statement*.csv');
            app.rawData = readcell(strcat(path,file));
            
            %Save the data in their respective variables
            app.Type = string(app.rawData(2:end,1));
            app.Product = string(app.rawData(2:end,2));
            app.StartDate = app.rawData(2:end,3);
            app.CompleteDate = app.rawData(2:end,4);
            app.Description = string(app.rawData(2:end,5));
            app.Amount = cell2mat(app.rawData(2:end,6));
            app.Fee = cell2mat(app.rawData(2:end,7));
            app.Currency = string(app.rawData(2:end,8));
            app.State = string(app.rawData(2:end,9));
            app.Balance = cell2mat(app.rawData(2:end,10));
            
            %Update UI elements with the new data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RevolutBudgetting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end